classdef TTRSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TTRSimulationUIFigure           matlab.ui.Figure
        BoardInitializationPanel        matlab.ui.container.Panel
        InitFunctionDropDown            matlab.ui.control.DropDown
        InitFunctionDropDownLabel       matlab.ui.control.Label
        ExecutionPanel                  matlab.ui.container.Panel
        NumberofparallelworkersSpinner  matlab.ui.control.Spinner
        NumberofparallelworkersSpinnerLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        FinalBoardStateTab              matlab.ui.container.Tab
        BoardAxes                       matlab.ui.control.UIAxes
        FinalMetricsTab                 matlab.ui.container.Tab
        NumTurnsAheadWithLongRouteAxes  matlab.ui.control.UIAxes
        NumTurnsAheadAxes               matlab.ui.control.UIAxes
        LongestRouteAxes                matlab.ui.control.UIAxes
        AvgRouteLengthAxes              matlab.ui.control.UIAxes
        RoutesClaimedAxes               matlab.ui.control.UIAxes
        TrainCardsLeftAxes              matlab.ui.control.UIAxes
        TurnsAxes                       matlab.ui.control.UIAxes
        TrainsPlayedAxes                matlab.ui.control.UIAxes
        DestinationCardsCompleteAxes    matlab.ui.control.UIAxes
        FinalScoresAxes                 matlab.ui.control.UIAxes
        ResetButton                     matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        ExportResultstoMATFileCheckBox  matlab.ui.control.CheckBox
        RulesPanel                      matlab.ui.container.Panel
        RandomSeedDropDown              matlab.ui.control.DropDown
        RandomSeedDropDownLabel         matlab.ui.control.Label
        NumberofIterationsSpinner       matlab.ui.control.Spinner
        NumberofIterationsSpinnerLabel  matlab.ui.control.Label
        RulesetDropDown                 matlab.ui.control.DropDown
        RulesetDropDownLabel            matlab.ui.control.Label
        PlayersPanel                    matlab.ui.container.Panel
        Player4DropDown                 matlab.ui.control.DropDown
        Player4DropDownLabel            matlab.ui.control.Label
        Player3DropDown                 matlab.ui.control.DropDown
        Player3DropDownLabel            matlab.ui.control.Label
        Player2DropDown                 matlab.ui.control.DropDown
        Player2DropDownLabel            matlab.ui.control.Label
        Player1DropDown                 matlab.ui.control.DropDown
        Player1DropDownLabel            matlab.ui.control.Label
        NumberofplayersSpinner          matlab.ui.control.Spinner
        NumberofplayersSpinnerLabel     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function runButtonPushed(app, event)
            cla(app.BoardAxes)
            cla(app.RoutesClaimedAxes)
            cla(app.TrainCardsLeftAxes)
            cla(app.TurnsAxes)
            cla(app.TrainsPlayedAxes)
            cla(app.DestinationCardsCompleteAxes)
            cla(app.FinalScoresAxes)
            cla(app.NumTurnsAheadAxes)
            cla(app.NumTurnsAheadWithLongRouteAxes)
            axes = struct('Board', app.BoardAxes, ...
                          'RoutesClaimed', app.RoutesClaimedAxes, ...
                          'TrainCardsLeft', app.TrainCardsLeftAxes, ...
                          'Turns', app.TurnsAxes, ...
                          'TrainsPlayed', app.TrainsPlayedAxes, ...
                          'DestinationCardsComplete', app.DestinationCardsCompleteAxes, ...
                          'FinalScores', app.FinalScoresAxes, ...
                          'AvgRouteLength', app.AvgRouteLengthAxes, ...
                          'LongestRoute', app.LongestRouteAxes, ...
                          'NumTurnsAhead', app.NumTurnsAheadAxes, ...
                          'NumTurnsAheadWithLongRoute', app.NumTurnsAheadWithLongRouteAxes);

            RunSimulation(evalin('base',strcat('@',app.InitFunctionDropDown.Value)), app.NumberofplayersSpinner.Value, ...
                                                app.NumberofIterationsSpinner.Value, ...
                                                { app.Player1DropDown.Value, ...
                                                  app.Player2DropDown.Value, ...
                                                  app.Player3DropDown.Value, ...
                                                  app.Player4DropDown.Value}, ...
                                                app.RulesetDropDown.Value, ...
                                                app.RandomSeedDropDown.Value, ...
                                                axes, app.NumberofparallelworkersSpinner.Value,...
                                                app.ExportResultstoMATFileCheckBox.Value);
        end

        % Value changed function: NumberofplayersSpinner
        function NumberofplayersSpinnerValueChanged(app, event)
            value = app.NumberofplayersSpinner.Value;
            if value < 2
                app.Player2DropDown.Enable = false;
                app.Player2DropDownLabel.Enable = false;
            else
                app.Player2DropDown.Enable = true;
                app.Player2DropDownLabel.Enable = true;
            end
            if value < 3
                app.Player3DropDown.Enable = false;
                app.Player3DropDownLabel.Enable = false;
            else
                app.Player3DropDown.Enable = true;
                app.Player3DropDownLabel.Enable = true;
            end
            if value < 4
                app.Player4DropDown.Enable = false;
                app.Player4DropDownLabel.Enable = false;
            else
                app.Player4DropDown.Enable = true;
                app.Player4DropDownLabel.Enable = true;
            end
        end

        % Button pushed function: ResetButton
        function resetButtonPushed(app, event)
            app.NumberofplayersSpinner.Value = 2;
            app.Player1DropDown.Value = "Dummy Player";
            app.Player2DropDown.Value = "Dummy Player";
            app.Player3DropDown.Value = "Dummy Player";
            app.Player4DropDown.Value = "Dummy Player";
            app.NumberofplayersSpinnerValueChanged(event);

            app.RulesetDropDown.Value = "Default Rules";
            app.NumberofIterationsSpinner.Value = 1;
            app.RandomSeedDropDown.Value = "Consistent";

            app.FormatDropDown.Value = "Default";

            cla(app.BoardAxes)
            cla(app.RoutesClaimedAxes)
            cla(app.TrainCardsLeftAxes)
            cla(app.TurnsAxes)
            cla(app.TrainsPlayedAxes)
            cla(app.DestinationCardsCompleteAxes)
            cla(app.FinalScoresAxes)
            cla(app.NumTurnsAheadAxes)
            cla(app.NumTurnsAheadWithLongRouteAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TTRSimulationUIFigure = uifigure('Visible', 'off');
            app.TTRSimulationUIFigure.Name = 'TTR Simulation';
            app.TTRSimulationUIFigure.Icon = 'icon.jpg';
            app.RunButton = uibutton(app.TTRSimulationUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PlayersPanel = uipanel(app.TTRSimulationUIFigure);
            app.PlayersPanel.Title = 'Players';
            app.NumberofplayersSpinnerLabel = uilabel(app.PlayersPanel);
            app.NumberofplayersSpinnerLabel.Text = 'Number of players';
            app.NumberofplayersSpinner = uispinner(app.PlayersPanel);
            app.NumberofplayersSpinner.Limits = [2 4];
            app.NumberofplayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofplayersSpinnerValueChanged, true);
            app.NumberofplayersSpinner.Value = 2;
            app.Player1DropDownLabel = uilabel(app.PlayersPanel);
            app.Player1DropDownLabel.Text = 'Player 1';
            app.Player1DropDown = uidropdown(app.PlayersPanel);
            app.Player1DropDown.Items = {'Dummy Player', 'Long Route Player', 'Destination Ticket Player', 'Deviant Player', 'Treacherous Player', 'Hybrid Player'};
            app.Player1DropDown.Value = 'Dummy Player';
            app.Player2DropDownLabel = uilabel(app.PlayersPanel);
            app.Player2DropDownLabel.Text = 'Player 2';
            app.Player2DropDown = uidropdown(app.PlayersPanel);
            app.Player2DropDown.Items = {'Dummy Player', 'Long Route Player', 'Destination Ticket Player', 'Deviant Player', 'Treacherous Player', 'Hybrid Player'};
            app.Player2DropDown.Value = 'Dummy Player';
            app.Player3DropDownLabel = uilabel(app.PlayersPanel);
            app.Player3DropDownLabel.Enable = 'off';
            app.Player3DropDownLabel.Text = 'Player 3';
            app.Player3DropDown = uidropdown(app.PlayersPanel);
            app.Player3DropDown.Items = {'Dummy Player', 'Long Route Player', 'Destination Ticket Player', 'Deviant Player', 'Treacherous Player', 'Hybrid Player'};
            app.Player3DropDown.Enable = 'off';
            app.Player3DropDown.Value = 'Dummy Player';
            app.Player4DropDownLabel = uilabel(app.PlayersPanel);
            app.Player4DropDownLabel.Enable = 'off';
            app.Player4DropDownLabel.Text = 'Player 4';
            app.Player4DropDown = uidropdown(app.PlayersPanel);
            app.Player4DropDown.Items = {'Dummy Player', 'Long Route Player', 'Destination Ticket Player', 'Deviant Player', 'Treacherous Player', 'Hybrid Player'};
            app.Player4DropDown.Enable = 'off';
            app.Player4DropDown.Value = 'Dummy Player';
            app.RulesPanel = uipanel(app.TTRSimulationUIFigure);
            app.RulesPanel.Title = 'Rules';
            app.RulesetDropDownLabel = uilabel(app.RulesPanel);
            app.RulesetDropDownLabel.Text = 'Ruleset';
            app.RulesetDropDown = uidropdown(app.RulesPanel);
            app.RulesetDropDown.Items = {'Default Rules', 'Treachery Rules', 'Destination Ticket Bonus Rules', 'Less Route Points Rules', 'More Actions Rules'};
            app.RulesetDropDown.Value = 'Default Rules';
            app.NumberofIterationsSpinnerLabel = uilabel(app.RulesPanel);
            app.NumberofIterationsSpinnerLabel.Text = 'Number of Iterations';
            app.NumberofIterationsSpinner = uispinner(app.RulesPanel);
            app.NumberofIterationsSpinner.Limits = [1 Inf];
            app.NumberofIterationsSpinner.Value = 1;
            app.RandomSeedDropDownLabel = uilabel(app.RulesPanel);
            app.RandomSeedDropDownLabel.Text = 'Random Seed';
            app.RandomSeedDropDown = uidropdown(app.RulesPanel);
            app.RandomSeedDropDown.Items = {'Consistent', 'Shuffle'};
            app.RandomSeedDropDown.Value = 'Consistent';
            app.OutputPanel = uipanel(app.TTRSimulationUIFigure);
            app.OutputPanel.Title = 'Output';
            app.ExportResultstoMATFileCheckBox = uicheckbox(app.OutputPanel);
            app.ExportResultstoMATFileCheckBox.Text = 'Export Results to MAT-File';
            app.ExportResultstoMATFileCheckBox.Value = true;
            app.ResetButton = uibutton(app.TTRSimulationUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TabGroup = uitabgroup(app.TTRSimulationUIFigure);
            app.FinalBoardStateTab = uitab(app.TabGroup);
            app.FinalBoardStateTab.Title = 'Final Board State';
            app.BoardAxes = uiaxes(app.FinalBoardStateTab);
            title(app.BoardAxes, 'Final Board State')
            zlabel(app.BoardAxes, 'Z')
            app.BoardAxes.XTick = [];
            app.BoardAxes.YTick = [];
            app.FinalMetricsTab = uitab(app.TabGroup);
            app.FinalMetricsTab.Title = 'Final Metrics';
            app.FinalScoresAxes = uiaxes(app.FinalMetricsTab);
            title(app.FinalScoresAxes, 'Player Final Scores')
            zlabel(app.FinalScoresAxes, 'Z')
            app.FinalScoresAxes.XTick = [];
            app.FinalScoresAxes.YTick = [];
            app.DestinationCardsCompleteAxes = uiaxes(app.FinalMetricsTab);
            title(app.DestinationCardsCompleteAxes, 'Player Destination Cards Completed')
            zlabel(app.DestinationCardsCompleteAxes, 'Z')
            app.DestinationCardsCompleteAxes.XTick = [];
            app.DestinationCardsCompleteAxes.YTick = [];
            app.TrainsPlayedAxes = uiaxes(app.FinalMetricsTab);
            title(app.TrainsPlayedAxes, 'Player Trains Played')
            zlabel(app.TrainsPlayedAxes, 'Z')
            app.TrainsPlayedAxes.XTick = [];
            app.TrainsPlayedAxes.YTick = [];
            app.TurnsAxes = uiaxes(app.FinalMetricsTab);
            title(app.TurnsAxes, 'Players'' Turns')
            zlabel(app.TurnsAxes, 'Z')
            app.TurnsAxes.XTick = [];
            app.TurnsAxes.YTick = [];
            app.TrainCardsLeftAxes = uiaxes(app.FinalMetricsTab);
            title(app.TrainCardsLeftAxes, 'Player Train Cards Left')
            zlabel(app.TrainCardsLeftAxes, 'Z')
            app.TrainCardsLeftAxes.XTick = [];
            app.TrainCardsLeftAxes.YTick = [];
            app.RoutesClaimedAxes = uiaxes(app.FinalMetricsTab);
            title(app.RoutesClaimedAxes, 'Players'' Routes Claimed')
            zlabel(app.RoutesClaimedAxes, 'Z')
            app.RoutesClaimedAxes.XTick = [];
            app.RoutesClaimedAxes.YTick = [];
            app.AvgRouteLengthAxes = uiaxes(app.FinalMetricsTab);
            title(app.AvgRouteLengthAxes, 'Player Average Route Length')
            zlabel(app.AvgRouteLengthAxes, 'Z')
            app.AvgRouteLengthAxes.XTick = [];
            app.AvgRouteLengthAxes.YTick = [];
            app.LongestRouteAxes = uiaxes(app.FinalMetricsTab);
            title(app.LongestRouteAxes, 'Player Longest Route')
            zlabel(app.LongestRouteAxes, 'Z')
            app.LongestRouteAxes.XTick = [];
            app.LongestRouteAxes.YTick = [];
            app.NumTurnsAheadAxes = uiaxes(app.FinalMetricsTab);
            title(app.NumTurnsAheadAxes, 'Player Num Turns Ahead')
            zlabel(app.NumTurnsAheadAxes, 'Z')
            app.NumTurnsAheadAxes.XTick = [];
            app.NumTurnsAheadAxes.YTick = [];
            app.NumTurnsAheadWithLongRouteAxes = uiaxes(app.FinalMetricsTab);
            title(app.NumTurnsAheadWithLongRouteAxes, 'Player Num Turns Ahead w/ Long Route')
            zlabel(app.NumTurnsAheadWithLongRouteAxes, 'Z')
            app.NumTurnsAheadWithLongRouteAxes.XTick = [];
            app.NumTurnsAheadWithLongRouteAxes.YTick = [];
            app.ExecutionPanel = uipanel(app.TTRSimulationUIFigure);
            app.ExecutionPanel.Title = 'Execution';
            app.NumberofparallelworkersSpinnerLabel = uilabel(app.ExecutionPanel);
            app.NumberofparallelworkersSpinnerLabel.Text = 'Number of parallel workers';
            app.NumberofparallelworkersSpinner = uispinner(app.ExecutionPanel);
            app.NumberofparallelworkersSpinner.Limits = [0 Inf];
            app.NumberofparallelworkersSpinner.Value = 6;
            app.BoardInitializationPanel = uipanel(app.TTRSimulationUIFigure);
            app.BoardInitializationPanel.Title = 'Board Initialization';
            app.InitFunctionDropDownLabel = uilabel(app.BoardInitializationPanel);
            app.InitFunctionDropDownLabel.Text = 'Init Function';
            app.InitFunctionDropDown = uidropdown(app.BoardInitializationPanel);
            app.InitFunctionDropDown.Items = {'initializeBasicGame', 'initializeSameRouteLengthGame'};
            app.InitFunctionDropDown.Value = 'initializeBasicGame';
            % Show the figure after all components are created
            app.TTRSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTRSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TTRSimulationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TTRSimulationUIFigure)
        end
    end
end